classdef AlgGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TrainingTab                     matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        TrainingResultsTextArea         matlab.ui.control.TextArea
        TrainingResultsTextArea_2Label  matlab.ui.control.Label
        ConfusionMatrixLabel            matlab.ui.control.Label
        UITable4                        matlab.ui.control.Table
        ModelRecordLabel                matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        TrainingModelConfigurationPanel  matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        FileNameEditField               matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        LayersEditField                 matlab.ui.control.EditField
        LayersEditFieldLabel            matlab.ui.control.Label
        EpochsEditField                 matlab.ui.control.NumericEditField
        EpochsEditFieldLabel            matlab.ui.control.Label
        beta2EditField                  matlab.ui.control.NumericEditField
        beta2EditFieldLabel             matlab.ui.control.Label
        beta1EditField                  matlab.ui.control.NumericEditField
        beta1EditFieldLabel             matlab.ui.control.Label
        MomentumEditField               matlab.ui.control.NumericEditField
        MomentumLabel                   matlab.ui.control.Label
        LearningRateEditField           matlab.ui.control.NumericEditField
        LearningRateEditFieldLabel      matlab.ui.control.Label
        ClearGraphButton                matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        zscorenormalisationCheckBox     matlab.ui.control.CheckBox
        StartButton                     matlab.ui.control.Button
        UploadTrainingTargetButton      matlab.ui.control.Button
        AlgorithmsButtonGroup           matlab.ui.container.ButtonGroup
        AbsAdamButton                   matlab.ui.control.RadioButton
        ExpAdamButton                   matlab.ui.control.RadioButton
        AdamButton                      matlab.ui.control.RadioButton
        HBMomButton                     matlab.ui.control.RadioButton
        SGDButton                       matlab.ui.control.RadioButton
        ModelButtonGroup                matlab.ui.container.ButtonGroup
        RBFButton                       matlab.ui.control.RadioButton
        MLPButton                       matlab.ui.control.RadioButton
        UploadTrainingDataButton        matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        TestingTab                      matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        DataInfoTextArea                matlab.ui.control.TextArea
        DataInfoTextAreaLabel           matlab.ui.control.Label
        Result2TextArea                 matlab.ui.control.TextArea
        Result2TextAreaLabel            matlab.ui.control.Label
        Result1TextArea                 matlab.ui.control.TextArea
        Result1TextAreaLabel            matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        UITable3                        matlab.ui.control.Table
        UITable2                        matlab.ui.control.Table
        DataSelectionPanel              matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        NumberofTestingDataEditField    matlab.ui.control.NumericEditField
        NumberofTestingDataEditFieldLabel  matlab.ui.control.Label
        SelectTargetButton              matlab.ui.control.Button
        PrepareButton                   matlab.ui.control.Button
        SelectDatasetButton             matlab.ui.control.Button
        Model2ConfigurationPanel        matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        StartButton_3                   matlab.ui.control.Button
        SelectModelButton_2             matlab.ui.control.Button
        Model1ConfigurationPanel        matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        StartButton_2                   matlab.ui.control.Button
        SelectModelButton               matlab.ui.control.Button
        DemoTab                         matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        LineWidthSpinner                matlab.ui.control.Spinner
        LineWidthSpinnerLabel           matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        ResultsPanel                    matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        PredictionTextArea              matlab.ui.control.TextArea
        PredictionTextAreaLabel         matlab.ui.control.Label
        PredictButton                   matlab.ui.control.Button
        Panel2                          matlab.ui.container.Panel
        TrainingConfigurationsPanel     matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        TrainingAccuracyTextArea        matlab.ui.control.TextArea
        TrainingAccuracyTextAreaLabel   matlab.ui.control.Label
        HiddenNeuronsEditField          matlab.ui.control.NumericEditField
        HiddenNeuronsEditFieldLabel     matlab.ui.control.Label
        LearningRateEditField_2         matlab.ui.control.NumericEditField
        LearningRateEditField_2Label    matlab.ui.control.Label
        EpochsEditField_2               matlab.ui.control.NumericEditField
        EpochsEditField_2Label          matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        TrainButton                     matlab.ui.control.Button
        LearningAlgorithmButtonGroup    matlab.ui.container.ButtonGroup
        AdamButton_2                    matlab.ui.control.RadioButton
        SGDButton_2                     matlab.ui.control.RadioButton
        DrawButton                      matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        UserDrawInputAxes               matlab.ui.control.UIAxes
    end

    properties (Access = public)
        TrainingLossLine

        historyAcc=[];
        historyLoss=[];
        currentAcc=[];
        currentLoss=[];
        currentTimeStamps=[];
        historyTimeStamps=[];

        currentValidationTimeStamps=[];
        historyValidationTimeStamps=[];
        historyAccVal=[];
        historyLossVal=[];
        currentAccVal=[];
        currentLossVal=[];

        ValidationData
    end
    properties (Access = private)
        path=genpath(pwd)


        TrainingModel='MLP';
        TrainingAlg='SGD';
        TestingModel1
        TestingAlg1
        TestingModel2
        TestingAlg2
        InputNumber=0;
        TestingInput
        TestingTarget
        TestingData
        TestingConfig
        lr=0.01;
        mom=0
        beta1=0.9;
        beta2=0.99;
        layers=[20];
        Epochs=10;
        file
        isDrawing=false;
        drawxlim=[0,1];
        drawylim=[0,1];
        continueDraw=false;
        DrawHiddenNeurons=20;
        finalpic;
        DrawModel;
        DrawTrained=false;
        Drawlr=0.01;
        DrawEpoch=1;
        Drawalg='SGD';

        % debugging flags (TrainingTab)
        trainingTargetfile
        trainingDatafile
        trainingfilename
        TrainingData
        TrainingTarget

        trainingdatacheck=false;
        trainingtargetcheck=false;
        trainingfilenamecheck=false;

        % debuggin flags (TestingTab)
        testingDatafile
        testingTargetfile
        model1file
        model2file
        
        testdatacheck=false;
        testtargetcheck=false;
        model1filecheck=false;
        model2filecheck=false;

    end

    properties (Access = public)
        normalise % Description
        xValue=0;
        yValue=0;
        linewidth=4;
        Border=1;
        
    end
    methods (Access=private)
        function [c1,c2,c3]=checkTrainingTab(app,~)

            c1=true; c2=true; c3=true;
%             if app.trainingDatafile==0 || app.TrainingData==0
            if isempty(app.trainingDatafile) || isempty(app.TrainingData)
                c1=false;
            end
%             if app.trainingTargetfile==0||app.TrainingTarget==0
            if isempty(app.trainingTargetfile) || isempty(app.TrainingTarget)
                c2=false;
            end
%             if app.file==0
            if isempty(app.file)
                c3=false;
            end

        end

        function [c1,c2]=checkTestingTabPrepare(app,~)
            c1=true; c2=true;
            if app.testingDatafile==0
                c1=false;
            end
            if app.testingTargetfile==0
                c2=false;
            end
        end
        
        function resetTestingTab(app,~)
            % debuggin flags (TestingTab)
            app.testingDatafile=0;
            app.testingTargetfile=0;
            app.model1file=0;
            app.model2file=0;
            app.UITable2.Data='';
            app.UITable2.ColumnName=[];
            app.UITable2.ColumnWidth='auto';
            app.Result1TextArea.Value='';
            app.UITable3.Data='';
            app.UITable3.ColumnName=[];
            app.UITable3.ColumnWidth='auto';
            app.Result2TextArea.Value='';
            app.DataInfoTextArea.Value='';
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Code inside your app's startupFcn
        end

        % Button pushed function: UploadTrainingDataButton
        function UploadTrainingDataButtonPushed(app, event)
            [app.trainingDatafile,path]=uigetfile('*.mat');
            if app.trainingDatafile~=0
                data=importdata([path app.trainingDatafile]);
                app.TrainingData=data;
                app.Lamp.Enable='on';
            end
        end

        % Button pushed function: UploadTrainingTargetButton
        function UploadTrainingTargetButtonPushed(app, event)
            [app.trainingTargetfile,path]=uigetfile('*.mat');
            if app.trainingTargetfile~=0
                target=importdata([path app.trainingTargetfile]);
                app.TrainingTarget=target;
            end
        end

        % Selection changed function: ModelButtonGroup
        function ModelButtonGroupSelectionChanged(app, event)
            value= app.ModelButtonGroup.SelectedObject;
            app.TrainingModel=value.Text;
        end

        % Selection changed function: AlgorithmsButtonGroup
        function AlgorithmsButtonGroupSelectionChanged(app, event)
            app.TrainingAlg = app.AlgorithmsButtonGroup.SelectedObject.Text;
        end

        % Callback function
        function HiddenLayersEditFieldValueChanged(app, event)
            value = app.HiddenLayersEditField.Value;
            app.layers=value;
        end

        % Value changed function: LearningRateEditField
        function LearningRateEditFieldValueChanged(app, event)
            app.lr = app.LearningRateEditField.Value;
        end

        % Value changed function: MomentumEditField
        function MomentumEditFieldValueChanged(app, event)
            app.mom = app.MomentumEditField.Value;
        end

        % Value changed function: beta1EditField
        function beta1EditFieldValueChanged(app, event)
            app.beta1 = app.beta1EditField.Value;
        end

        % Value changed function: beta2EditField
        function beta2EditFieldValueChanged(app, event)
            app.beta2 = app.beta2EditField.Value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            [c1,c2,c3]=checkTrainingTab(app,event);
            if ~c1
                uialert(app.UIFigure,'No training data imported.',...
                    'invalid file');
            
            elseif ~c2
                uialert(app.UIFigure,'No training target imported.',...
                    'invalid file');
            
            elseif ~c3
                uialert(app.UIFigure,'Please enter a valid filename.',...
                    'invalid file');
            
            else
                app.UITable4.Data='';
                app.TrainingResultsTextArea.Value='';
                app.Lamp.Enable='on';
                title(app.UIAxes,'');
                app.UITable.ColumnName={'Training Samples';'Testing Samples';'Model';...
                    'Recognition Algorithm';'Hidden Layer Neurons';...
                    'No. of Epoches';'Training Time(sec)';...
                    'Avg. System Accuracy(%)';...
                    'Precesion(%)';'Recall(%)';'F1score(%)'};
                config.alpha=app.lr;
                config.mom=app.mom;
                config.beta1=app.beta1;
                config.beta2=app.beta2;
                config.layers=app.layers;
                config.gradAlgo=app.TrainingAlg;
                config.epochs=app.Epochs;
                features.Input=app.TrainingData;
                features.Target=app.TrainingTarget;
    
                if strcmp(app.TrainingModel,'RBF')
                    output=newrbf(app,features,config);
                else
                    output=newmlp(app,features,config);
                end
                if ~exist(strcat('training/model/',app.file),'dir')
                    mkdir(strcat('training/model/',app.file))
                end
                filename=strcat('training/model/',app.file,'/',num2str(size(config.layers,2)),...
                    config.gradAlgo,app.TrainingModel,'.mat');
                model=output.net;
                model.gradAlgo=config.gradAlgo;
                save(filename,'model');
                app.Lamp.Enable='off';
                printResults(app.TrainingResultsTextArea,app.UITable4,output.results);
                writeResults(output.results,strcat('training/model/',app.file,'/results.csv'));
                plot(app.UIAxes,1:length(output.results.errorRecord),output.results.errorRecord);
                xlim(app.UIAxes,'auto')
                ylim(app.UIAxes,'auto')
                title(app.UIAxes,'Algorithm Performance')
                xlabel(app.UIAxes,'Epoch')
                ylabel(app.UIAxes,'Training Cost')
    
                table=readtable(strcat('training/model/',app.file,'/results.csv'));
                app.UITable.Data=table;
                app.UITable.ColumnWidth={50,50,50,70,50,50,70,70};
            end
        end

        % Value changed function: EpochsEditField
        function EpochsEditFieldValueChanged(app, event)
            app.Epochs = app.EpochsEditField.Value;
        end

        % Value changed function: LayersEditField
        function LayersEditFieldValueChanged(app, event)
            value = app.LayersEditField.Value;
            s=split(value,',');
            layer=[];
            for i=1:length(s)
                layer=[layer str2num(s{i})];
            end
            app.layers=layer;
        end

        % Value changed function: zscorenormalisationCheckBox
        function zscorenormalisationCheckBoxValueChanged(app, event)
            app.normalise = app.zscorenormalisationCheckBox.Value;
        end

        % Callback function
        function ModelDropDownValueChanged(app, event)
            app.TestingModel1 = app.ModelDropDown.Value;
        end

        % Callback function
        function AlgorithmDropDownValueChanged(app, event)
            app.TestingAlg1 = app.AlgorithmDropDown.Value;
        end

        % Callback function
        function HiddenLayersDropDownValueChanged(app, event)
            app.TestingLayers1 = str2num(app.HiddenLayersDropDown.Value);
        end

        % Callback function
        function ModelDropDown_2ValueChanged(app, event)
            app.TestingModel2 = app.ModelDropDown_2.Value;
        end

        % Callback function
        function AlgorithmDropDown_2ValueChanged(app, event)
            app.TestingAlg2 = app.AlgorithmDropDown_2.Value;
        end

        % Callback function
        function HiddenLayersDropDown_2ValueChanged(app, event)
            app.TestingLayers = str2num(app.HiddenLayersDropDown_2.Value);
        end

        % Button pushed function: SelectDatasetButton
        function SelectDatasetButtonPushed(app, event)
            [app.testingDatafile,path]=uigetfile('*.mat');
            if app.testingDatafile~=0
                data=importdata([path app.testingDatafile]);
                app.TestingData=data;
            end
        end

        % Callback function
        function NumberofInputsEditFieldValueChanged(app, event)

        end

        % Value changed function: NumberofTestingDataEditField
        function NumberofTestingDataEditFieldValueChanged(app, event)
            app.InputNumber=app.NumberofTestingDataEditField.Value;
        end

        % Value changing function: LayersEditField
        function LayersEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.layers=changingValue;
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            app.file = app.FileNameEditField.Value;
        end

        % Button pushed function: ClearGraphButton
        function ClearGraphButtonPushed(app, event)
            cla(app.UIAxes);
            hold(app.UIAxes);
        end

        % Button pushed function: SelectTargetButton
        function SelectTargetButtonPushed(app, event)
            [app.testingTargetfile,path]=uigetfile('*.mat');
            if app.testingTargetfile~=0
                target=importdata([path app.testingTargetfile]);
                app.TestingTarget=target;
            end
        end

        % Button pushed function: PrepareButton
        function PrepareButtonPushed(app, event)
            [c1,c2]=checkTestingTabPrepare(app,event);
            if ~c1
                uialert(app.UIFigure,'No Dataset imported.', 'invalid file');
            
            elseif ~c2
                uialert(app.UIFigure,'No Target imported.','invalid file');
            
            
            else
                app.DataInfoTextArea.Value{1}=strcat(...
                    'Number of Samples : ',num2str(size(app.TestingData,1)));
                app.DataInfoTextArea.Value{2}=strcat(...
                    'Number of Classes : ',num2str(size(app.TestingTarget,2)));
                p=randperm(size(app.TestingData,1));
                Input=app.TestingData(p,:);
                app.InputNumber=min([app.InputNumber,size(app.TestingData,1)]);
                Target=app.TestingTarget(p,:);
                app.TestingConfig.input=Input(1:app.InputNumber,:);
                app.TestingConfig.target=Target(1:app.InputNumber,:);
                noutput=size(app.TestingConfig.target,2);
                tem=sum(app.TestingConfig.target,1);
                matrix=zeros(noutput);
                for i=1:noutput
                    matrix(i,i)=tem(i);
                end
    
                matrix=string(matrix);
                app.DataInfoTextArea.Value{3}='Confusion Matrix : ';
                for i=1:noutput
                    s="";
                    for j=1:noutput
                        s= s+ sprintf(" %4s",matrix(i,j));
                    end
                    app.DataInfoTextArea.Value{i+3}=char(s);
                end
            end
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            [c1,c2]=checkTestingTabPrepare(app);
            if ~c1 || ~c2
                uialert(app.UIFigure,'Please select dataset and target.','invalid file');
            
            elseif app.model1file==0
                uialert(app.UIFigure,'Please select a model file.','invalid file');
            
            else
                output=applynetwork(app.TestingModel1,app.TestingConfig.input);
                [~,cm,~,~]=confusion(app.TestingConfig.target',output);
                R1 = newevaluation(app.TestingConfig.target', output);
                cm=array2table(cm);
                app.UITable2.Data=cm;
                app.UITable2.ColumnName=num2cell(1:size(cm,1));
                app.UITable2.ColumnWidth=num2cell(ones(1,size(cm,1))*40);
                j=3;
                app.Result1TextArea.Value{j+1}=strcat(...
                    'Average System Accuracy(%)   : ', num2str(R1.avgAccuracy));
                app.Result1TextArea.Value{j+2}=strcat(...
                    'Precision (%)         : ', num2str(R1.precisionMicro));
                app.Result1TextArea.Value{j+3}=strcat(...
                    'Recall (%)            : ', num2str(R1.recallMicro));
                app.Result1TextArea.Value{j+4}=strcat(...
                    'Fscore (%)            : ', num2str(R1.fscoreMicro));
            end
        end

        % Button pushed function: SelectModelButton
        function SelectModelButtonPushed(app, event)
            [app.model1file path]=uigetfile('*.mat');
            if app.model1file~=0
                net=importdata([[path app.model1file]]);
                app.TestingModel1=net;
                app.Result1TextArea.Value={''};
                layers=[];
                for i=1:size(net.struct)-1
                    layers=[layers net.struct(i,1)];
                end
                app.Result1TextArea.Value{2}=strcat('Learning Algorithm: ',net.gradAlgo);
                app.Result1TextArea.Value{3}=strcat('Layers: ',num2str(layers));
                app.Result1TextArea.Value{1}=strcat('Model: ',net.model);
            end
        end

        % Button pushed function: StartButton_3
        function StartButton_3Pushed(app, event)
            [c1,c2]=checkTestingTabPrepare(app);
            if ~c1 || ~c2
                uialert(app.UIFigure,'Please select dataset and target.','invalid file');
            
            elseif app.model2file==0
                uialert(app.UIFigure,'Please select a model file.','invalid file');
            
            else
                output=applynetwork(app.TestingModel2,app.TestingConfig.input);
                [~,cm,~,~]=confusion(app.TestingConfig.target',output);
                R2 = newevaluation(app.TestingConfig.target', output);
                cm=array2table(cm);
                app.UITable3.Data=cm;
                app.UITable3.ColumnName=num2cell(1:size(cm,1));
                app.UITable3.ColumnWidth=num2cell(ones(1,size(cm,1))*40);
                j=3;
                app.Result2TextArea.Value{j+1}=strcat(...
                    'Average System Accuracy(%)   : ', num2str(R2.avgAccuracy));
                app.Result2TextArea.Value{j+2}=strcat(...
                    'Precision (%)         : ', num2str(R2.precisionMicro));
                app.Result2TextArea.Value{j+3}=strcat(...
                    'Recall (%)            : ', num2str(R2.recallMicro));
                app.Result2TextArea.Value{j+4}=strcat(...
                    'Fscore (%)            : ', num2str(R2.fscoreMicro));
            end
        end

        % Button pushed function: SelectModelButton_2
        function SelectModelButton_2Pushed(app, event)
            [app.model2file path]=uigetfile('*.mat');
            net=importdata([path app.model2file]);
            if app.model2file~=0
                app.TestingModel2=net;
                app.Result2TextArea.Value={''};
                layers=[];
                for i=1:size(net.struct)-1
                    layers=[layers net.struct(i,1)];
                end
                app.Result2TextArea.Value{2}=strcat('Learning Algorithm: ',net.gradAlgo);
                app.Result2TextArea.Value{3}=strcat('Layers: ',num2str(layers));
                app.Result2TextArea.Value{1}=strcat('Model: ',net.model);
            end
        end

        % Callback function: DrawButton, UserDrawInputAxes
        function UserDrawInputAxesButtonDown(app, event)
            app.isDrawing = true;
            app.PredictButton.Enable='off';
            app.PredictionTextArea.Value='';
            app.DrawButton.Enable='off';
            app.SaveButton.Enable='on';
            app.ClearButton.Enable='on';
%             handles=app.UserDrawInputAxes;
%             app.UserDrawInputAxes.currentLine = line('XData', [], 'YData', [], 'Color', 'k', 'LineWidth', 2);
%             if handles.isDrawing
%                 currPoint = app.UserDrawInputAxes.CurrentPoint;
%                 x = currPoint(1,1);
%                 y = currPoint(1,2);
%                 app.UserDrawInputAxes.currentLine.XData = [handles.currentLine.XData x];
%                 app.UserDrawInputAxes.currentLine.YData = [handles.currentLine.YData y];
%             end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)

        end

        % Button down function: DemoTab
        function DemoTabButtonDown(app, event)
            app.isDrawing=false;
            fprintf('You are now on Demo page.\n')
            set(app.UserDrawInputAxes,'XTick', [],'YTick',[])
            app.UserDrawInputAxes.XLim=app.drawxlim;
            app.UserDrawInputAxes.YLim=app.drawylim;
        end

        % Button down function: TrainingTab
        function TrainingTabButtonDown(app, event)
            app.isDrawing=false;
        end

        % Button down function: TestingTab
        function TestingTabButtonDown(app, event)
            app.isDrawing=false;
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Callback function
        function ButtonPushed(app, event)
%             line(app.UserDrawInputAxes,[1,2],[2,3])
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UserDrawInputAxes);
            app.isDrawing=false;
            if exist('axes.png','file')
                delete('axes.png')
            end
            app.DrawButton.Enable='on';
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if app.isDrawing
                app.continueDraw=true;
                hold(app.UserDrawInputAxes,'on') 
                cp = app.UserDrawInputAxes.CurrentPoint;           
                app.xValue=cp(1,1);
                app.yValue=cp(1,2);
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if app.isDrawing && app.continueDraw
            cp = app.UserDrawInputAxes.CurrentPoint;
            hold(app.UserDrawInputAxes,'on') 
            line(app.UserDrawInputAxes,[app.xValue cp(1,1)],...
                [app.yValue,cp(1,2)],'LineWidth',app.linewidth,'Color','black')
           app.xValue =  cp(1,1);
           app.yValue =  cp(1,2);
           drawnow
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if app.isDrawing
                app.continueDraw=false;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
           app.isDrawing=false;
           app.continueDraw=false;
           exportgraphics(app.UserDrawInputAxes,'axes.png')
           app.DrawButton.Enable='off';
           app.PredictButton.Enable='on';
           
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            if ~app.DrawTrained
                uialert(app.UIFigure,'No trained model imported.',...
                    'invalid file')
            elseif ~exist('axes.png','file')
                uialert(app.UIFigure,{'No image selected.',...
                    'Click "save" after drawing'},...
                    'invalid file')
            else
                pic=imread('axes.png');
                inc=70;
               app.finalpic=255-rgb2gray(pic(23:end-3,4:end,:));
               app.finalpic=imresize(app.finalpic,[28,28]);
               ind = (app.finalpic>0 ).* (app.finalpic<255-inc);
               app.finalpic=app.finalpic+uint8(ind.*inc);
               finalpic=app.finalpic;
               
                YPred = classify(app.DrawModel,app.finalpic);
                app.PredictionTextArea.Value=YPred;
            end
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            app.TrainButton.Enable="off";


            digitDatasetPath = fullfile(matlabroot,'toolbox','nnet','nndemos', ...
                        'nndatasets','DigitDataset');
            imds = imageDatastore(digitDatasetPath, ...
                'IncludeSubfolders',true,'LabelSource','foldernames');
            numTrainFiles = 750;
            [imdsTrain,imdsValidation] = splitEachLabel(imds,numTrainFiles,'randomize');
            layers = [
                imageInputLayer([28 28 1])
                
                convolution2dLayer(3,8,'Padding','same')
                batchNormalizationLayer
                reluLayer
                
                maxPooling2dLayer(2,'Stride',2)
                
                convolution2dLayer(3,16,'Padding','same')
                batchNormalizationLayer
                reluLayer
                
                maxPooling2dLayer(2,'Stride',2)
                
                convolution2dLayer(3,32,'Padding','same')
                batchNormalizationLayer
                reluLayer

                fullyConnectedLayer(app.DrawHiddenNeurons)
                reluLayer
                
                fullyConnectedLayer(10)
                softmaxLayer
                classificationLayer];
            if strcmp(app.Drawalg,'SGD')
                drawalg='sgdm';
            else
                drawalg='adam';
            end
            options = trainingOptions(drawalg, ...
                'InitialLearnRate',app.Drawlr, ...
                'MaxEpochs',app.DrawEpoch, ...
                'Shuffle','every-epoch', ...
                'ValidationData',imdsValidation, ...
                'ValidationFrequency',30, ...
                'OutputFcn',@(info) myOutputFcn(app,info), ...
                'Plots','training-progress');
            net = trainNetwork(imdsTrain,layers,options);
            YPred = classify(net,imdsValidation);
            YValidation = imdsValidation.Labels;
            classNames={'0','1','2','3','4','5','6','7','8','9'};
        
            tfig = uifigure('Name', 'Confusion Matrix', 'Position', [122,133,371,310]);
            t = uitable(tfig, 'Position', [88,41,241,205]);
            % Compute the confusion matrix
            confMat = confusionmat(YValidation, YPred);    
            t.RowName = classNames;
            t.ColumnName = classNames;
            % Set the ColumnWidth and FontSize properties
            t.ColumnWidth = 'fit';
            t.FontSize = 12;
            data=confMat;
            uitableObj=t;
            % Set uitable position and size
            uitableObj.Position = [88 80 size(data, 2) *40-28 size(data, 1) * 25+4];
            
            % Calculate uifigure size based on uitable size and position
            uitableSize = uitableObj.Position(3:4);
            uitablePos = uitableObj.Position(1:2);
            figWidth = uitablePos(1) + uitableSize(1) + 20;
            figHeight = uitablePos(2) + uitableSize(2) + 80;
            
            % Set uifigure size
            tfig.Position(3:4) = [figWidth, figHeight];
            % Set the uitable Data, RowName, and ColumnName properties
            t.Data = confMat;
% 
            label1=uilabel(tfig);
            label2=uilabel(tfig);

            label1.Position = [15,182,80,22]; % [left, bottom, width, height]
            label1.FontSize = 14;
            label1.WordWrap='on';
            label1.Text = 'True Class';
            
            label2.Position = [231,343,180,22]; % [left, bottom, width, height]
            label2.FontSize = 14;
            label1.WordWrap='on';
            label2.Text = 'Predicted Class';

            app.DrawModel=net;
            accuracy = sum(YPred == YValidation)/numel(YValidation);
            app.TrainingAccuracyTextArea.Value=strcat(num2str(accuracy*100),"%");
            app.Lamp_2.Enable='on';
            app.TrainButton.Enable='on';
            app.DrawTrained=true;

        end

        % Value changed function: LineWidthSpinner
        function LineWidthSpinnerValueChanged(app, event)
            app.linewidth = app.LineWidthSpinner.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete('axes.png')
            delete(app)
            
        end

        % Value changed function: EpochsEditField_2
        function EpochsEditField_2ValueChanged(app, event)
            if ~isnumeric(app.EpochsEditField_2.Value) || ...
                    app.EpochsEditField_2.Value<=0
                uialert(app.UIFigure,'Please enter a positive integer',...
                    'invalid file')
            else
                app.DrawEpoch = app.EpochsEditField_2.Value;
            end
        end

        % Value changed function: LearningRateEditField_2
        function LearningRateEditField_2ValueChanged(app, event)
            if ~isnumeric(str2double(app.LearningRateEditField_2.Value)) || ...
                    str2double(app.LearningRateEditField_2.Value)<=0 ||...
                    str2double(app.LearningRateEditField_2.Value)>=1
                uialert(app.UIFigure,'Please enter a value between 0 and 1.',...
                    'invalid file')
            else
                app.Drawlr = app.LearningRateEditField_2.Value;
            end
        end

        % Selection changed function: LearningAlgorithmButtonGroup
        function LearningAlgorithmButtonGroupSelectionChanged(app, event)
            app.Drawalg = app.LearningAlgorithmButtonGroup.SelectedObject.Text;
        end

        % Value changed function: HiddenNeuronsEditField
        function HiddenNeuronsEditFieldValueChanged(app, event)
            if ~isnumeric(app.HiddenNeuronsEditField.Value)
                uialert(app.UIFigure,'Only a positive integer is accepted', ...
                    'invalid file');
            elseif app.HiddenNeuronsEditField.Value >100
                uialert(app.UIFigure,'Please enter a number less than 100',...
                    'invalid file');
                app.HiddenNeuronsEditField.Value=100;
            else
                app.DrawHiddenNeurons = app.HiddenNeuronsEditField.Value;     
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            resetTestingTab(app,event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TrainingTab = uitab(app.TabGroup);
            app.TrainingTab.Title = 'Training';
            app.TrainingTab.ButtonDownFcn = createCallbackFcn(app, @TrainingTabButtonDown, true);
            app.GridLayout12 = uigridlayout(app.TrainingTab);
            app.UIAxes = uiaxes(app.GridLayout12);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TrainingModelConfigurationPanel = uipanel(app.GridLayout12);
            app.TrainingModelConfigurationPanel.Title = 'Training Model Configuration';
            app.GridLayout9 = uigridlayout(app.TrainingModelConfigurationPanel);
            app.UploadTrainingDataButton = uibutton(app.GridLayout9, 'push');
            app.UploadTrainingDataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadTrainingDataButtonPushed, true);
            app.UploadTrainingDataButton.Text = 'Upload Training Data';
            app.ModelButtonGroup = uibuttongroup(app.GridLayout9);
            app.ModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelButtonGroupSelectionChanged, true);
            app.ModelButtonGroup.Title = 'Model';
            app.MLPButton = uiradiobutton(app.ModelButtonGroup);
            app.MLPButton.Text = 'MLP';
            app.MLPButton.Value = true;
            app.RBFButton = uiradiobutton(app.ModelButtonGroup);
            app.RBFButton.Text = 'RBF';
            app.AlgorithmsButtonGroup = uibuttongroup(app.GridLayout9);
            app.AlgorithmsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlgorithmsButtonGroupSelectionChanged, true);
            app.AlgorithmsButtonGroup.Title = 'Algorithms';
            app.SGDButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.SGDButton.Text = 'SGD';
            app.SGDButton.Value = true;
            app.HBMomButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.HBMomButton.Text = 'HBMom';
            app.AdamButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.AdamButton.Text = 'Adam';
            app.ExpAdamButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.ExpAdamButton.Text = 'ExpAdam';
            app.AbsAdamButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.AbsAdamButton.Text = 'AbsAdam';
            app.UploadTrainingTargetButton = uibutton(app.GridLayout9, 'push');
            app.UploadTrainingTargetButton.ButtonPushedFcn = createCallbackFcn(app, @UploadTrainingTargetButtonPushed, true);
            app.UploadTrainingTargetButton.Text = 'Upload Training Target';
            app.StartButton = uibutton(app.GridLayout9, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.zscorenormalisationCheckBox = uicheckbox(app.GridLayout9);
            app.zscorenormalisationCheckBox.ValueChangedFcn = createCallbackFcn(app, @zscorenormalisationCheckBoxValueChanged, true);
            app.zscorenormalisationCheckBox.Text = 'z-score normalisation';
            app.Lamp = uilamp(app.GridLayout9);
            app.Lamp.Enable = 'off';
            app.ClearGraphButton = uibutton(app.GridLayout9, 'push');
            app.ClearGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ClearGraphButtonPushed, true);
            app.ClearGraphButton.Text = 'Clear Graph';
            app.LearningRateEditFieldLabel = uilabel(app.GridLayout9);
            app.LearningRateEditFieldLabel.Text = 'Learning Rate';
            app.LearningRateEditField = uieditfield(app.GridLayout9, 'numeric');
            app.LearningRateEditField.Limits = [0 1];
            app.LearningRateEditField.ValueChangedFcn = createCallbackFcn(app, @LearningRateEditFieldValueChanged, true);
            app.LearningRateEditField.Value = 0.01;
            app.MomentumLabel = uilabel(app.GridLayout9);
            app.MomentumLabel.Text = '    Momentum';
            app.MomentumEditField = uieditfield(app.GridLayout9, 'numeric');
            app.MomentumEditField.Limits = [0 1];
            app.MomentumEditField.ValueChangedFcn = createCallbackFcn(app, @MomentumEditFieldValueChanged, true);
            app.beta1EditFieldLabel = uilabel(app.GridLayout9);
            app.beta1EditFieldLabel.Text = 'beta1';
            app.beta1EditField = uieditfield(app.GridLayout9, 'numeric');
            app.beta1EditField.Limits = [0 1];
            app.beta1EditField.ValueChangedFcn = createCallbackFcn(app, @beta1EditFieldValueChanged, true);
            app.beta1EditField.Value = 0.9;
            app.beta2EditFieldLabel = uilabel(app.GridLayout9);
            app.beta2EditFieldLabel.Text = 'beta2';
            app.beta2EditField = uieditfield(app.GridLayout9, 'numeric');
            app.beta2EditField.Limits = [0 1];
            app.beta2EditField.ValueChangedFcn = createCallbackFcn(app, @beta2EditFieldValueChanged, true);
            app.beta2EditField.Value = 0.99;
            app.EpochsEditFieldLabel = uilabel(app.GridLayout9);
            app.EpochsEditFieldLabel.Text = 'Epochs';
            app.EpochsEditField = uieditfield(app.GridLayout9, 'numeric');
            app.EpochsEditField.Limits = [1 Inf];
            app.EpochsEditField.RoundFractionalValues = 'on';
            app.EpochsEditField.ValueChangedFcn = createCallbackFcn(app, @EpochsEditFieldValueChanged, true);
            app.EpochsEditField.Value = 10;
            app.LayersEditFieldLabel = uilabel(app.GridLayout9);
            app.LayersEditFieldLabel.Text = 'Layers';
            app.LayersEditField = uieditfield(app.GridLayout9, 'text');
            app.LayersEditField.ValueChangedFcn = createCallbackFcn(app, @LayersEditFieldValueChanged, true);
            app.LayersEditField.ValueChangingFcn = createCallbackFcn(app, @LayersEditFieldValueChanging, true);
            app.LayersEditField.Value = '20';
            app.FileNameEditFieldLabel = uilabel(app.GridLayout9);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.GridLayout9, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.UITable = uitable(app.GridLayout12);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ModelRecordLabel = uilabel(app.GridLayout12);
            app.ModelRecordLabel.Text = 'Model Record';
            app.UITable4 = uitable(app.GridLayout12);
            app.UITable4.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable4.RowName = {};
            app.ConfusionMatrixLabel = uilabel(app.GridLayout12);
            app.ConfusionMatrixLabel.Text = 'Confusion Matrix';
            app.TrainingResultsTextArea_2Label = uilabel(app.GridLayout12);
            app.TrainingResultsTextArea_2Label.Text = 'Training Results';
            app.TrainingResultsTextArea = uitextarea(app.GridLayout12);
            app.TestingTab = uitab(app.TabGroup);
            app.TestingTab.Title = 'Testing';
            app.TestingTab.ButtonDownFcn = createCallbackFcn(app, @TestingTabButtonDown, true);
            app.GridLayout2 = uigridlayout(app.TestingTab);
            app.Model1ConfigurationPanel = uipanel(app.GridLayout2);
            app.Model1ConfigurationPanel.Title = 'Model 1 Configuration';
            app.GridLayout8 = uigridlayout(app.Model1ConfigurationPanel);
            app.SelectModelButton = uibutton(app.GridLayout8, 'push');
            app.SelectModelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectModelButtonPushed, true);
            app.SelectModelButton.Text = 'Select Model';
            app.StartButton_2 = uibutton(app.GridLayout8, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.Model2ConfigurationPanel = uipanel(app.GridLayout2);
            app.Model2ConfigurationPanel.Title = 'Model 2 Configuration';
            app.GridLayout7 = uigridlayout(app.Model2ConfigurationPanel);
            app.SelectModelButton_2 = uibutton(app.GridLayout7, 'push');
            app.SelectModelButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectModelButton_2Pushed, true);
            app.SelectModelButton_2.Text = 'Select Model';
            app.StartButton_3 = uibutton(app.GridLayout7, 'push');
            app.StartButton_3.ButtonPushedFcn = createCallbackFcn(app, @StartButton_3Pushed, true);
            app.StartButton_3.Text = 'Start';
            app.DataSelectionPanel = uipanel(app.GridLayout2);
            app.DataSelectionPanel.Title = 'Data Selection';
            app.GridLayout6 = uigridlayout(app.DataSelectionPanel);
            app.SelectDatasetButton = uibutton(app.GridLayout6, 'push');
            app.SelectDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDatasetButtonPushed, true);
            app.SelectDatasetButton.Text = 'Select Dataset';
            app.PrepareButton = uibutton(app.GridLayout6, 'push');
            app.PrepareButton.ButtonPushedFcn = createCallbackFcn(app, @PrepareButtonPushed, true);
            app.PrepareButton.Text = 'Prepare';
            app.SelectTargetButton = uibutton(app.GridLayout6, 'push');
            app.SelectTargetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTargetButtonPushed, true);
            app.SelectTargetButton.Text = 'Select Target';
            app.NumberofTestingDataEditFieldLabel = uilabel(app.GridLayout6);
            app.NumberofTestingDataEditFieldLabel.Text = 'Number of Testing Data';
            app.NumberofTestingDataEditField = uieditfield(app.GridLayout6, 'numeric');
            app.NumberofTestingDataEditField.Limits = [0 Inf];
            app.NumberofTestingDataEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofTestingDataEditFieldValueChanged, true);
            app.UITable2 = uitable(app.GridLayout2);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.GridLayout2);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Result1TextAreaLabel = uilabel(app.GridLayout2);
            app.Result1TextAreaLabel.Text = 'Result 1';
            app.Result1TextArea = uitextarea(app.GridLayout2);
            app.Result2TextAreaLabel = uilabel(app.GridLayout2);
            app.Result2TextAreaLabel.Text = 'Result 2';
            app.Result2TextArea = uitextarea(app.GridLayout2);
            app.DataInfoTextAreaLabel = uilabel(app.GridLayout2);
            app.DataInfoTextAreaLabel.Text = 'Data Info';
            app.DataInfoTextArea = uitextarea(app.GridLayout2);
            app.DemoTab = uitab(app.TabGroup);
            app.DemoTab.Title = 'Demo';
            app.DemoTab.ButtonDownFcn = createCallbackFcn(app, @DemoTabButtonDown, true);
            app.GridLayout11 = uigridlayout(app.DemoTab);
            app.UserDrawInputAxes = uiaxes(app.GridLayout11);
            title(app.UserDrawInputAxes, 'Drawing Input')
            app.UserDrawInputAxes.ButtonDownFcn = createCallbackFcn(app, @UserDrawInputAxesButtonDown, true);
            app.SaveButton = uibutton(app.GridLayout11, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.ClearButton = uibutton(app.GridLayout11, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Enable = 'off';
            app.ClearButton.Text = 'Clear';
            app.DrawButton = uibutton(app.GridLayout11, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @UserDrawInputAxesButtonDown, true);
            app.DrawButton.Text = 'Draw';
            app.TrainingConfigurationsPanel = uipanel(app.GridLayout11);
            app.TrainingConfigurationsPanel.Title = 'Training Configurations';
            app.GridLayout4 = uigridlayout(app.TrainingConfigurationsPanel);
            app.LearningAlgorithmButtonGroup = uibuttongroup(app.GridLayout4);
            app.LearningAlgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LearningAlgorithmButtonGroupSelectionChanged, true);
            app.LearningAlgorithmButtonGroup.Title = 'Learning Algorithm';
            app.SGDButton_2 = uiradiobutton(app.LearningAlgorithmButtonGroup);
            app.SGDButton_2.Text = 'SGD';
            app.SGDButton_2.Value = true;
            app.AdamButton_2 = uiradiobutton(app.LearningAlgorithmButtonGroup);
            app.AdamButton_2.Text = 'Adam';
            app.TrainButton = uibutton(app.GridLayout4, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.Lamp_2 = uilamp(app.GridLayout4);
            app.Lamp_2.Enable = 'off';
            app.EpochsEditField_2Label = uilabel(app.GridLayout4);
            app.EpochsEditField_2Label.Text = 'Epochs';
            app.EpochsEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.EpochsEditField_2.Limits = [1 100];
            app.EpochsEditField_2.RoundFractionalValues = 'on';
            app.EpochsEditField_2.ValueChangedFcn = createCallbackFcn(app, @EpochsEditField_2ValueChanged, true);
            app.EpochsEditField_2.Value = 1;
            app.LearningRateEditField_2Label = uilabel(app.GridLayout4);
            app.LearningRateEditField_2Label.Text = 'Learning Rate';
            app.LearningRateEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.LearningRateEditField_2.Limits = [0 1];
            app.LearningRateEditField_2.ValueChangedFcn = createCallbackFcn(app, @LearningRateEditField_2ValueChanged, true);
            app.LearningRateEditField_2.Value = 0.01;
            app.HiddenNeuronsEditFieldLabel = uilabel(app.GridLayout4);
            app.HiddenNeuronsEditFieldLabel.Text = 'Hidden Neurons';
            app.HiddenNeuronsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.HiddenNeuronsEditField.Limits = [1 100];
            app.HiddenNeuronsEditField.RoundFractionalValues = 'on';
            app.HiddenNeuronsEditField.ValueChangedFcn = createCallbackFcn(app, @HiddenNeuronsEditFieldValueChanged, true);
            app.HiddenNeuronsEditField.Value = 20;
            app.TrainingAccuracyTextAreaLabel = uilabel(app.GridLayout4);
            app.TrainingAccuracyTextAreaLabel.Text = 'Training Accuracy:';
            app.TrainingAccuracyTextArea = uitextarea(app.GridLayout4);
            app.Panel2 = uipanel(app.GridLayout11);
            app.Panel2.Title = 'Panel2';
            app.ResultsPanel = uipanel(app.GridLayout11);
            app.ResultsPanel.Title = 'Results';
            app.GridLayout5 = uigridlayout(app.ResultsPanel);
            app.PredictButton = uibutton(app.GridLayout5, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Enable = 'off';
            app.PredictButton.Text = 'Predict';
            app.PredictionTextAreaLabel = uilabel(app.GridLayout5);
            app.PredictionTextAreaLabel.Text = 'Prediction: ';
            app.PredictionTextArea = uitextarea(app.GridLayout5);
            app.ButtonGroup = uibuttongroup(app.GridLayout11);
            app.ButtonGroup.Title = 'Button Group';
            app.LineWidthSpinnerLabel = uilabel(app.GridLayout11);
            app.LineWidthSpinnerLabel.Text = 'LineWidth';
            app.LineWidthSpinner = uispinner(app.GridLayout11);
            app.LineWidthSpinner.Limits = [1 8];
            app.LineWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @LineWidthSpinnerValueChanged, true);
            app.LineWidthSpinner.Value = 4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AlgGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end